classdef GUI2_fou_gy < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        RightPanel      matlab.ui.container.Panel
        TabGroup        matlab.ui.container.TabGroup
        Tab             matlab.ui.container.Tab
        UIAxes          matlab.ui.control.UIAxes
        Tab2            matlab.ui.container.Tab
        UIAxes2         matlab.ui.control.UIAxes
        Tab3            matlab.ui.container.Tab
        UIAxes3         matlab.ui.control.UIAxes
        Tab4            matlab.ui.container.Tab
        LeftPanel       matlab.ui.container.Panel
        LoadFileButton  matlab.ui.control.Button
        RunButton       matlab.ui.control.Button
    end

    
    properties (Access = public)
        loadfile % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadFileButton
        function LoadFileButtonPushed(app, event)
            filterspec = {'*.jpg;*.tif;*.png;*.gif;*.dcm','All Image Files'};
            [f, p] = uigetfile(filterspec);
            if (ischar(p))
               
            app.loadfile = [p f];
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            im=imread(app.loadfile,2);
            app.UIAxes.Visible="off";
            app.UIAxes2.Visible="off";
            app.UIAxes3.Visible="off";
            imagesc(app.UIAxes,im);
            imagesc(app.UIAxes2,im);
            imagesc(app.UIAxes3,im);
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RightPanel = uipanel(app.UIFigure);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.UIAxes = uiaxes(app.Tab);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.UIAxes2 = uiaxes(app.Tab2);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Title = 'Tab3';
            app.UIAxes3 = uiaxes(app.Tab3);
            title(app.UIAxes3, '')
            xlabel(app.UIAxes3, '')
            ylabel(app.UIAxes3, '')
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            app.Tab4 = uitab(app.TabGroup);
            app.Tab4.Title = 'Tab4';
            app.LeftPanel = uipanel(app.UIFigure);
            app.LoadFileButton = uibutton(app.LeftPanel, 'push');
            app.LoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFileButtonPushed, true);
            app.LoadFileButton.Text = 'LoadFile';
            app.RunButton = uibutton(app.LeftPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI2_fou_gy

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end